classdef Mortgage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MortgageCalculatorUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        cEditField                  matlab.ui.control.NumericEditField
        cEditFieldLabel             matlab.ui.control.Label
        CheckBox                    matlab.ui.control.CheckBox
        bEditField                  matlab.ui.control.NumericEditField
        bEditFieldLabel             matlab.ui.control.Label
        aEditField                  matlab.ui.control.NumericEditField
        aEditFieldLabel             matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        PrincipalInterestUIAxes     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MortgageCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {316, 316};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function MonthlyPaymentButtonPushed(app, event)
                       
            % Calculate the monthly payment
            amount = app.LoanAmountEditField.Value;
            rate = app.aEditField.Value/12/100;
            nper = 12*app.bEditField.Value;
            payment = (amount*rate)/(1-(1+rate)^-nper);
            app.MonthlyPaymentEditField.Value = payment;
            
            % pre allocating and initializing variables
            interest = zeros(1,nper);
            principal = zeros(1,nper);
            balance = zeros (1,nper);
            
            balance(1) = amount;
            
            % Calculate the principal and interest over time
            for i = 1:nper
                interest(i)  = balance(i)*rate;
                principal(i) = payment - interest(i);
                balance(i+1) = balance(i) - principal(i);
            end
            
            % Plot the principal and interest
            plot(app.PrincipalInterestUIAxes,(1:nper)',principal, ...
                (1:nper)',interest);
            legend(app.PrincipalInterestUIAxes,{'Principal','Interest'},'Location','Best')
            xlim(app.PrincipalInterestUIAxes,[0 nper]); 
            
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            value = app.bEditField.Value;
            
        end

        % Button down function: PrincipalInterestUIAxes
        function PrincipalInterestUIAxesButtonDown(app, event)
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value
                x = 0:10;
                y = app.aEditField.Value*x.^2 + app.bEditField.Value*x + app.cEditField.Value*x;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MortgageCalculatorUIFigure = uifigure('Visible', 'off');
            app.MortgageCalculatorUIFigure.Name = 'Mortgage Calculator';
            app.MortgageCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MortgageCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.aEditFieldLabel = uilabel(app.LeftPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.LeftPanel, 'numeric');
            app.aEditField.Limits = [0.001 100];
            app.aEditField.Value = 4;
            app.bEditFieldLabel = uilabel(app.LeftPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.LeftPanel, 'numeric');
            app.bEditField.Limits = [10 40];
            app.bEditField.ValueDisplayFormat = '%.0f';
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.bEditField.Value = 30;
            app.CheckBox = uicheckbox(app.LeftPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.cEditFieldLabel = uilabel(app.LeftPanel);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.LeftPanel, 'numeric');
            app.cEditField.Limits = [10 40];
            app.cEditField.ValueDisplayFormat = '%.0f';
            app.cEditField.Value = 30;
            app.RightPanel = uipanel(app.GridLayout);
            app.PrincipalInterestUIAxes = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes, 'Principal and Interest')
            xlabel(app.PrincipalInterestUIAxes, 'Time (Months)')
            ylabel(app.PrincipalInterestUIAxes, 'Amount')
            app.PrincipalInterestUIAxes.ButtonDownFcn = createCallbackFcn(app, @PrincipalInterestUIAxesButtonDown, true);
            % Show the figure after all components are created
            app.MortgageCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mortgage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MortgageCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MortgageCalculatorUIFigure)
        end
    end
end